classdef GravNetAdj_English < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GravNetAdjPro                   matlab.ui.Figure
        Relative_GravityMenu            matlab.ui.container.Menu
        Para_SetMenu                    matlab.ui.container.Menu
        Input_DataMenu                  matlab.ui.container.Menu
        RelGrav_CompMenu                matlab.ui.container.Menu
        Outlier_detectionMenu           matlab.ui.container.Menu
        AdjustmentData_loadMenu         matlab.ui.container.Menu
        ObsdataMenu                     matlab.ui.container.Menu
        ReldataMenu                     matlab.ui.container.Menu
        Observation_AnalysisMenu        matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        Plot_graphMenu                  matlab.ui.container.Menu
        AdjustmentMenu                  matlab.ui.container.Menu
        ParameterMenu                   matlab.ui.container.Menu
        ComputeMenu                     matlab.ui.container.Menu
        return_out                      matlab.ui.container.Menu
        exit_out                        matlab.ui.container.Menu
        SetPanel                        matlab.ui.container.Panel
        InstrumentHightCheckBox         matlab.ui.control.CheckBox
        Parameter_SelectionPanel        matlab.ui.container.Panel
        CoordinateSelectionButtonGroup  matlab.ui.container.ButtonGroup
        OldCoordButton                  matlab.ui.control.RadioButton
        NewCoordButton                  matlab.ui.control.RadioButton
        CodeSelectionButtonGroup        matlab.ui.container.ButtonGroup
        GBKButton                       matlab.ui.control.RadioButton
        UTF8Button                      matlab.ui.control.RadioButton
        GradientSelectionButtonGroup    matlab.ui.container.ButtonGroup
        OldGradButton                   matlab.ui.control.RadioButton
        NewGradButton                   matlab.ui.control.RadioButton
        ErrorCorrectionPanel            matlab.ui.container.Panel
        AtmosphericPressureCheckBox     matlab.ui.control.CheckBox
        OceanTideCheckBox               matlab.ui.control.CheckBox
        SolidTideCheckBox               matlab.ui.control.CheckBox
        Adjustment_ComputePanel         matlab.ui.container.Panel
        ComputePanel                    matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        StatisticsPanel                 matlab.ui.container.Panel
        ShortBaselineCheckBox           matlab.ui.control.CheckBox
        InstrumentCheckBox              matlab.ui.control.CheckBox
        PointofreferenceCheckBox        matlab.ui.control.CheckBox
        East_westCheckBox               matlab.ui.control.CheckBox
        Basic_PointCheckBox             matlab.ui.control.CheckBox
        Datum_PointCheckBox             matlab.ui.control.CheckBox
        residualCheckBox                matlab.ui.control.CheckBox
        Parameter_modulPanel            matlab.ui.container.Panel
        SetPanel_5                      matlab.ui.container.Panel
        Adjustment_MethodButtonGroup    matlab.ui.container.ButtonGroup
        RobustButton                    matlab.ui.control.RadioButton
        LSButton                        matlab.ui.control.RadioButton
        VCESetPanel                     matlab.ui.container.Panel
        VCEeParameter                   matlab.ui.control.NumericEditField
        e2Label                         matlab.ui.control.Label
        Num_ObsParameter                matlab.ui.control.NumericEditField
        Num_ObsLabel                    matlab.ui.control.Label
        Robust_SetPanel                 matlab.ui.container.Panel
        iter_numberLabel                matlab.ui.control.Label
        iter_number                     matlab.ui.control.EditField
        RobusteParameter                matlab.ui.control.NumericEditField
        e1Label                         matlab.ui.control.Label
        K2Parameter                     matlab.ui.control.NumericEditField
        k2Label                         matlab.ui.control.Label
        K1Parameter                     matlab.ui.control.NumericEditField
        k1Label                         matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        InstrumentParameterButton       matlab.ui.control.Button
        InstrumentParameter             matlab.ui.control.EditField
        InstrumentParameterLabel        matlab.ui.control.Label
        Observation_analysisPanel       matlab.ui.container.Panel
        SetPanel_3                      matlab.ui.container.Panel
        CloseLoop_gezhiButtonGroup      matlab.ui.container.ButtonGroup
        usedButton                      matlab.ui.control.RadioButton
        UnusedButton                    matlab.ui.control.RadioButton
        CloseLoopPanel                  matlab.ui.container.Panel
        ToleranceParameter              matlab.ui.control.NumericEditField
        ToleranceLabel                  matlab.ui.control.Label
        AnalysisModulePanel             matlab.ui.container.Panel
        Statistics_CompButton           matlab.ui.control.Button
        DataAnalysisPanel               matlab.ui.container.Panel
        AprioriPosDD_2                  matlab.ui.control.DropDown
        AnalysisLabel                   matlab.ui.control.Label
        Anysis_CompButton               matlab.ui.control.Button
        DatastatsticsPanel              matlab.ui.container.Panel
        AprioriPosDD                    matlab.ui.control.DropDown
        ObservationsLabel               matlab.ui.control.Label
        OutlieranalysisPanel            matlab.ui.container.Panel
        Outlier_DetButton               matlab.ui.control.Button
        InputFilePanel_2                matlab.ui.container.Panel
        ObservationFileLabel            matlab.ui.control.Label
        ObsFile                         matlab.ui.control.EditField
        ObsFileButton                   matlab.ui.control.Button
        Yiqigezhi                       matlab.ui.control.EditField
        YiqigezhiTableLabel             matlab.ui.control.Label
        YiqigezhiButton                 matlab.ui.control.Button
        SetPanel_2                      matlab.ui.container.Panel
        Limite_ValueEditField           matlab.ui.control.NumericEditField
        Limite_ValueEditFieldLabel      matlab.ui.control.Label
        MainLabel                       matlab.ui.control.Label
        quasi_geodetic_2                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        InputFilePanel                  matlab.ui.container.Panel
        GradientFile                    matlab.ui.control.EditField
        GradientFileLabel               matlab.ui.control.Label
        GradientFileButton              matlab.ui.control.Button
        CoordinateFile                  matlab.ui.control.EditField
        CoordinateFileLabel             matlab.ui.control.Label
        CoordinateFileButton            matlab.ui.control.Button
        Gezhi                           matlab.ui.control.EditField
        GezhiFileLabel                  matlab.ui.control.Label
        GezhiButton                     matlab.ui.control.Button
        RelativeAdjustment              matlab.ui.control.EditField
        RelativeAdjustmentFileLabel     matlab.ui.control.Label
        RelativeAdjustmentButton        matlab.ui.control.Button
        RelativeObservation             matlab.ui.control.EditField
        RelativeObservationFileLabel    matlab.ui.control.Label
        RelativeObservationButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Relative_GravityMenu
        function Relative_GravityMenuSelected(app, event)
            
        end

        % Menu selected function: Input_DataMenu
        function Input_DataMenuSelected(app, event)
                 app.InputFilePanel.Visible = 'on';
                 app.quasi_geodetic_2.Visible = 'off';
                 app.SetPanel.Visible = 'off';
                 app.OutlieranalysisPanel.Visible = 'off';
                 app.Observation_analysisPanel.Visible = 'off';
                 app.Parameter_modulPanel.Visible = 'off';
                 app.Adjustment_ComputePanel.Visible = 'off';
        end

        % Button pushed function: RelativeObservationButton
        function RelativeObservationButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the RelativeObservation file');
                 if ischar(file)
                    RelativeObservation = path;
                    app.RelativeObservation.Editable = 'on';
                    app.RelativeObservation.Value    = RelativeObservation;
                    app.RelativeObservation.Editable = 'off';
                 else
                    return
               end
        end

        % Button pushed function: GezhiButton
        function GezhiButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the gezhi file');
                 if ischar(file)
                    Gezhi = path;
                    app.Gezhi.Editable = 'on';
                    app.Gezhi.Value    = Gezhi;
                    app.Gezhi.Editable = 'off';
                 else
                    return
                 end
        end

        % Button pushed function: CoordinateFileButton
        function CoordinateFileButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the RelativeObservation file');
                 if ischar(file)
                    CoordinateFile = fullfile(path,file);
                    app.CoordinateFile.Editable = 'on';
                    app.CoordinateFile.Value    = CoordinateFile;
                    app.CoordinateFile.Editable = 'off';
                 else
                    return
                 end
        end

        % Button pushed function: GradientFileButton
        function GradientFileButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the RelativeObservation file');
                 if ischar(file)
                    GradientFile = fullfile(path,file);
                    app.GradientFile.Editable = 'on';
                    app.GradientFile.Value    = GradientFile;
                    app.GradientFile.Editable = 'off';
                 else
                    return
                 end
        end

        % Button pushed function: RelativeAdjustmentButton
        function RelativeAdjustmentButtonPushed(app, event)
                  [file,path] = uigetfile({'*.*'},'Select the RelativeObservation file');
                 if ischar(file)
                    GradientFile = fullfile(path,file);
                    app.GradientFile.Editable = 'on';
                    app.GradientFile.Value    = GradientFile;
                    app.GradientFile.Editable = 'off';
                 else
                    return
                 end
        end

        % Menu selected function: Para_SetMenu
        function Para_SetMenuSelected(app, event)
                 app.InputFilePanel.Visible = 'off';
                 app.OutlieranalysisPanel.Visible = 'off';
                 app.quasi_geodetic_2.Visible = 'off';
                 app.SetPanel.Visible = 'on';
                 app.OutlieranalysisPanel.Visible = 'off';
                 app.Parameter_modulPanel.Visible = 'off';
                 app.Adjustment_ComputePanel.Visible = 'off';
        end

        % Menu selected function: RelGrav_CompMenu
        function RelGrav_CompMenuSelected(app, event)
                numtObs = dir(strcat(app.RelativeObservation.Value,'*.txt'));  
                 numtgezhi = dir(strcat(app.Gezhi.Value,'*.txt'));   
                 if strcmp(app.CoordinateSelectionButtonGroup.SelectedObject.Text,'NewCoord')
                    fid = fopen(app.CoordinateFile.Value ,'r');  
                    Coord = textscan(fid,'%s %f %f %f %f %f %f');
                    fclose(fid);
                 else
                     Coord = [];
                 end
                 if strcmp(app.GradientSelectionButtonGroup.SelectedObject.Text,'NewGrad')
                     fid = fopen(app.GradientFile.Value,'r');
                     gradient = textscan(fid,'%s %f');
                     fclose(fid);
                 else
                     gradient = [];
                 end 
                 if isempty(double(app.RelativeObservation.Value))
                     errordlg('There are no road for relative gravity observations');
                     return;
                 end
                 if isempty(double(app.Gezhi.Value))
                     errordlg('There are no road for gezhi file');
                     return;
                 end
                 fid1  = fopen(app.RelativeAdjustment.Value,'w');
                 if fid1 <0
                     errordlg('There are no data storage file');
                     return;
                 end
                 f = waitbar(0.0000001,{'Please wait ...' 'Opeteration is processing'});
                 aadj = [];
                 for s = 1:length(numtObs(:,1))
%                      numtObs(s,:).name
                     [xdobs,deg,getr,gutu,yiqigai,qiya,ocean_load,kk,sst] = Gravity_correction(numtObs(s,:),numtgezhi,Coord,gradient,app.OceanTideCheckBox.Value);
                     %*************************************************
                     asd = lipiao_rev(xdobs,deg,getr,gutu,yiqigai,qiya,ocean_load,kk,sst,app.SolidTideCheckBox.Value,app.AtmosphericPressureCheckBox.Value,app.InstrumentHightCheckBox.Value,app.OceanTideCheckBox.Value);   
                     aadj = [aadj;asd];
                     waitbar(s/length(numtObs(:,1)),f);
                  end
                  aadj = sortrows(aadj,[3,4,2]);
                  for j = 1:length(aadj(:,1))
                      fprintf(fid1,'%5s  %10s %5s %5s %10.3f %10.3f %14.3f %14.3f %15.3f %10.5f %3d %3d\n',aadj{j,:});
                  end
                  fclose(fid1);
                  waitbar(1,f,{'The computation has finished' 'Operation Successfully'});
        end

        % Button pushed function: ObsFileButton
        function ObsFileButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the RelativeObservation file');
                 if ischar(file)
                    ObsFile = fullfile(path,file);
                    app.ObsFile.Editable = 'on';
                    app.ObsFile.Value    = ObsFile;
                    app.ObsFile.Editable = 'off';
                 else
                    return
                 end
        end

        % Button pushed function: YiqigezhiButton
        function YiqigezhiButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the RelativeObservation file');
                 if ischar(file)
                    Yiqigezhi = fullfile(path,file);
                    app.Yiqigezhi.Editable = 'on';
                    app.Yiqigezhi.Value    = Yiqigezhi;
                    app.Yiqigezhi.Editable = 'off';
                 else
                    return
                 end
        end

        % Button pushed function: Outlier_DetButton
        function Outlier_DetButtonPushed(app, event)
                 fid = fopen( app.ObsFile.Value,'r');
                 if fid<0
                     errordlg('      there are no observations ');
                     return;
                 end
                 data = textscan(fid,'%s %f %s %s %f %f %f %f %f %f %f %f');
                 fclose(fid);
                 tim = data{1,2};
                 fid  = fopen(app.Yiqigezhi.Value,'r');
                 if fid<0
                     errordlg('       there are no gezhi file');
                     gezhii = [];
                     return;
                 else
                     gezhii = textscan(fid,'%s %f');
                 end
                 app.gezhii = gezhii;
                 fclose(fid);
                 f = waitbar(0.0001,{'The outlier detection of segment difference' 'Opeteration is processing'});
                 xdObsNN = cell(length(data{1,1}),3);
                 xdObsNN(:,1) = data{1,1};xdObsNN(:,2) = data{1,3};xdObsNN(:,3) = data{1,4};
                 xdObss = zeros(length(data{1,1+4}),8);
                 xdObsNum = zeros(length(xdObss(:,1)),1);
                 for i = 1:8
                    xdObss(:,i) = double(data{1,i+4});
                 end
                 for i= 1:5
                     DC = gezhi_ducha(xdObsNN,xdObss,gezhii);
                     stt = 0;
                     posi_err = [];
                     for j = 1:length(DC(:,1))
                         if DC{j,end}>app.Limite_ValueEditField.Value
                            stt = stt + 1;
                            aa = find(double(strcmp(DC{j,1},xdObsNN(:,2))) == 1);
                            bb = find(double(strcmp(DC{j,2},xdObsNN(:,3))) == 1);
                            cc = intersect(aa,bb);
                            posi_err(stt) = cc(find((max(abs(xdObss(cc,5) - DC{j,4})) == abs(xdObss(cc,5) - DC{j,4})) == 1));
                         end
                     end
                     if stt == 0
                        break;
                     end
                     xdObsNN(posi_err,:) = [];xdObss(posi_err,:) = [];
                     tim(posi_err,:) = [];
                     waitbar(i/5,f);
                 end 
                 Cpath = strsplit(app.ObsFile.Value,'\'); 
                 fid = fopen(strrep(app.ObsFile.Value,Cpath{end},'adjust_data_new.txt'),'w');
                 for i= 1:length(xdObsNN(:,1))
                     fprintf(fid,'%5s  %10d %5s %5s %10.3f %10.3f %14.3f %14.3f %15.3f %10.5f %3d %3d\n', xdObsNN{i,1}, tim(i),xdObsNN{i,2:3},xdObss(i,:));
                 end
                 fclose(fid)
                 waitbar(1,f,{'Outlier detection has finished' 'Operation Successfully'});
        end

        % Selection changed function: CoordinateSelectionButtonGroup
        function CoordinateSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.CoordinateSelectionButtonGroup.SelectedObject;
            if strcmp(app.CoordinateSelectionButtonGroup.SelectedObject.Text,'NewCoord')
               app.CoordinateFile.Enable = 'on' ;             
               app.CoordinateFile.Editable = 'on';
               app.CoordinateFileButton.Enable = 'on';
            else
               app.CoordinateFile.Enable = 'off';
               app.CoordinateFile.Editable = 'off';
               app.CoordinateFileButton.Enable = 'off';
            end
        end

        % Selection changed function: GradientSelectionButtonGroup
        function GradientSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.GradientSelectionButtonGroup.SelectedObject;
            if strcmp(app.GradientSelectionButtonGroup.SelectedObject.Text,'NewGrad')
               app.GradientFile.Enable = 'on' ;             
               app.GradientFile.Editable = 'on';
               app.GradientFileButton.Enable = 'on';
            else
               app.GradientFile.Enable = 'off';
               app.GradientFile.Editable = 'off';
               app.GradientFileButton.Enable = 'off';
            end 

        end

        % Selection changed function: CodeSelectionButtonGroup
        function CodeSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.CodeSelectionButtonGroup.SelectedObject;
            if strcmp(app.CodeSelectionButtonGroup.SelectedObject.Text,'GBK')
                slCharacterEncoding('GBK');
            else
                slCharacterEncoding('UTF-8');
            end
        end

        % Menu selected function: Outlier_detectionMenu
        function Outlier_detectionMenuSelected(app, event)
              app.InputFilePanel.Visible = 'off';
              app.quasi_geodetic_2.Visible = 'off';
              app.SetPanel.Visible = 'off';
              app.OutlieranalysisPanel.Visible = 'on';
              app.Observation_analysisPanel.Visible = 'off';
              app.Parameter_modulPanel.Visible = 'off';
              app.Adjustment_ComputePanel.Visible = 'off';
        end

        % Menu selected function: AnalysisMenu
        function AnalysisMenuSelected(app, event)
              app.InputFilePanel.Visible = 'off';
              app.quasi_geodetic_2.Visible = 'off';
              app.SetPanel.Visible = 'off';
              app.OutlieranalysisPanel.Visible = 'off';
              app.Observation_analysisPanel.Visible = 'on';
              app.Parameter_modulPanel.Visible = 'off';
              app.Adjustment_ComputePanel.Visible = 'off';
        end

        % Callback function
        function ObsGravity_ProcessingMenuSelected(app, event)
              app.InputFilePanel.Visible = 'off';
              app.quasi_geodetic_2.Visible = 'off';
              app.SetPanel.Visible = 'off';
              app.OutlieranalysisPanel.Visible = 'off';
              app.Observation_analysisPanel.Visible = 'off';
              app.Parameter_modulPanel.Visible = 'off';
              app.Adjustment_ComputePanel.Visible = 'off';
        end

        % Menu selected function: ParameterMenu
        function ParameterMenuSelected(app, event)
              app.Parameter_modulPanel.Visible = 'off';
              app.InputFilePanel.Visible = 'off';
              app.quasi_geodetic_2.Visible = 'off';
              app.SetPanel.Visible = 'off';
              app.OutlieranalysisPanel.Visible = 'off';
              app.Observation_analysisPanel.Visible = 'off';
              app.Parameter_modulPanel.Visible = 'on';
              app.Adjustment_ComputePanel.Visible = 'off';
        end

        % Menu selected function: AdjustmentMenu
        function AdjustmentMenuSelected(app, event)
            
        end

        % Menu selected function: ComputeMenu
        function ComputeMenuSelected(app, event)
                 app.InputFilePanel.Visible = 'off';
                 app.quasi_geodetic_2.Visible = 'off';
                 app.SetPanel.Visible = 'off';
                 app.OutlieranalysisPanel.Visible = 'off';
                 app.Observation_analysisPanel.Visible = 'off';
                 app.Parameter_modulPanel.Visible = 'off';
                 app.Adjustment_ComputePanel.Visible = 'on'; 
        end

        % Button pushed function: Anysis_CompButton
        function Anysis_CompButtonPushed(app, event)
                 global xdObsN,XdObs,jdObsN,ImportAbsFile
                 if isempty(xdObsN)
                     errordlg('you do not input the relative gravity observations');
                     return;
                 end
                 if isempty(jdObsN)
                     errordlg('you do not input the absolute gravity observations');
                     return;
                 end
                 Cpath = strsplit(ImportAbsFile,'\'); 
                 if  strcmp(app.AprioriPosDD.Value,'AbsObs')
                     %**************************************
                    [stat_judui,tt] = stat_abs(jdObsN,jdObs);
                    
                    fid = fopen(strrep(ImportAbsFile,Cpath{end},'The statistical results of absolute gravity.txt'),'w');
                    for i = 1:length(stat_judui)
                        fprintf(fid,'%5s  %2d  %12.3f  %6.3f\n',stat_judui{i,1},tt(i,:));   
                    end
                    f = msgbox('The statistic of absolute gravity is finished:Operation Successfully');
                 else
                    fid = fopen(strrep(ImportAbsFile,Cpath{end},'The statistical results of segement difference.txt'),'w');
                    AA = stat_ducha(xdObsN,xdObs);
                    for ss = 1:length(AA(:,1))
                      fprintf(fid,'%5s  %5s %2d %15.3f %6.2f %8.4f\n',AA{ss,:});
                    end
                    f = msgbox('The statistical results of segement difference is finished:Operation Successfully'); 
                 end
                fclose(fid);  
        end

        % Button pushed function: Statistics_CompButton
        function Statistics_CompButtonPushed(app, event)
                  global xdObsN,xdObs,jdObs,jdObsN,numJ,numX,yiObs,ImportAbsFile,unObsN,gezhii
                  if isempty(xdObsN)
                     errordlg('please input the relative gravity data');
                     return;
                 end
                 if isempty(jdObsN)
                     errordlg('please input the absolute gravity data');
                     return;
                 end
                 if isempty(AA)
                     errordlg('please give the statistics of segement difference');
                     return;
                 end
                 AA = stat_ducha(xdObsN,xdObs);
                 Cpath = strsplit(ImportAbsFile,'\'); 
                 if strcmp(app.AprioriPosDD_2.Value,'ObsNetwork')
                    fid = fopen(strrep(ImportAbsFile,Cpath{end},'analys of netwrok.txt'),'w');
                    unObsJD(1,1) = jdObsN(1,1);
                    st3 = 0;
                    for i = 1:numJ
                        if strcmp(unObsJD,jdObsN(i,1)) == zeros(length(unObsJD),1)
                           st3 = st3 + 1;
                           unObsJD(st3,1) = jdObsN (i,1);
                        end
                    end
                   fprintf(fid,'number of instrument：%d\n',length(yiObs));
                   for i = 1:length(yiObs)
                       fprintf(fid,'%s\n',yiObs{i,1});
                   end
                   fprintf(fid,'\n obsolute observation point:%d\n',st3);
                   for i = 1:st3
                       fprintf(fid,'%s\n',unObsJD{i,1});
                    end
                   fprintf(fid,'\n total point number:%d\n',length(unObsN));
                   for i = 1:length(unObsN)
                       fprintf(fid,'%s\n',unObsN{i});
                   end
                   f = msgbox('The analysis of observation network is finished:Operation Successfully');
                 elseif strcmp(app.AprioriPosDD_2.Value,'ConnectedGrap')
                    fid = fopen(strrep(ImportAbsFile,Cpath{end},'ConnectedGraph.txt'),'w');
                    G = graph(AA(:,1)',AA(:,2)');
                    figure(1)
                    plot(G);
                    binss = conncomp(G,'OutputForm','cell');
                    s1 = 0;
                    for i = 1:length(binss)
                        fprintf(fid,'%s %d %s %s %d\n','The',i,'Connected Graph','number of point:',length(binss{i}));
                        for j = 1:length(binss{i})
                           fprintf(fid,'%6s\n',binss{i}{j});
                       end
                     end
                     f = msgbox('Tha analysis of conntected:Operation Successfully');
                 elseif strcmp(app.AprioriPosDD_2.Value,'YiqiPara')
                     file_raod = strrep(ImportAbsFile,Cpath{end},'The_statistic_results_for_insturment_parameter.txt');
                     success = yiqi_stat(file_raod,yiObs,xdObsN,AA);
                 else  
                    if strcmp(app.GradientSelectionButtonGroup.SelectedObject.Text,'Used')
                       if isempty(gezhii)
                          AAA = AA;
                       else
                          AAA = gezhi_ducha(xdObsN,xdObs,gezhii);
                       end
                    else
                       AAA = AA;
                    end
                    file_raod = strrep(ImportAbsFile,Cpath{end},'The_statistic_results_for_close_Loop.txt');
                    m_Sigma = app.ToleranceParameter.Value;
                    success = lsLoop(AAA,m_Sigma,unObsN,file_raod);           
                end
                fclose(fid);
        end

        % Callback function
        function AbsoluteObservationButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the AbsoluteObservation file');
                 if ischar(file)
                    AbsFile = fullfile(path,file);
                    app.AbsFile.Editable = 'on';
                    app.AbsFile.Value    = AbsFile;
                    app.AbsFile.Editable = 'off';
                 else
                    return
                 end   
        end

        % Callback function
        function AbsoluteAdjustmentButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the AbsoluteObservation file');
                 if ischar(file)
                    AbssaveFile = fullfile(path,file);
                    app.AbssaveFile.Editable = 'on';
                    app.AbssaveFile.Value    = AbssaveFile;
                    app.AbssaveFile.Editable = 'off';
                 else
                    return
                 end 
        end

        % Callback function
        function AbsGrav_CompButtonPushed(app, event)
                 if isempty(double(app.AbsFile.Value))
                     errordlg('it donot get the rode for storing the original relative gravity observation data');
                     return;
                 end
                 f = waitbar(0.0000001,{'Please wait ...' 'Opeteration is processing'});
                 num_abs = dir(strcat(app.AbsFile.Value,'*.txt'));
                 numfile_abs = length(num_abs(:,1));
                 fid  = fopen(app.AbssaveFile.Value,'w');
                 Cpath = strsplit(app.AbssaveFile.Valu,'\'); 
                 if strcmp(app.Instrument_selectionButtonGroup.SelectedObject,'FG-5')
                    for i = 1:numfile_abs
                        result(i) = absgravF_pro(num_abs(i,:)) ;
                        fprintf(fid,'%5s  %15.3f\n',Cpath{end}(1:3),result(i));
                        waitbar(i/numfile_abs,f);
                    end
                 else
                     for i = 1:numfile_abs
                        result(i) = absgravA_pro(num_abs(i,:));
                        fprintf(fid,'%5s %15.3f\n',Cpath{end}(1:4),result(i));
                        waitbar(i/numfile_abs,f);
                     end
                 end 
                 waitbar(1,f,{'Finished' 'Operation Successfully'});   
        end

        % Button pushed function: InstrumentParameterButton
        function InstrumentParameterButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the InstrumentParameter file');
                 if ischar(file)
                    InstrumentParameter = fullfile(path,file);
                    app.InstrumentParameter.Editable = 'on';
                    app.InstrumentParameter.Value    = InstrumentParameter;
                    app.InstrumentParameter.Editable = 'off';
                 else
                    return
                 end
                 fid=fopen(app.InstrumentParameter.Value ,'r');
                 data = textscan(fid,'%s %f %f %f %f %f %f %f %f %f');
                 yiparaN = {};
                 yiparaN(:,1) = data{1,1};
                 yipara =[];
                 for i = 1:length(data)-1
                     yipara(:,i) = data{1,i+1};
                 end
                 global yiparaN
                 global yipara              
        end

        % Callback function
        function ImportAbsFileButtonPushed(app, event)
                 [file,path] = uigetfile({'*.*'},'Select the AbsAdjustment file');
                  ImportAbsFile = fullfile(path,file);
                  fid = fopen(ImportAbsFile,'r');
                  if fid<0
                      errordlg('The file for input has error');
                  end
                  data = textscan(fid,'%s %f %f');
                  length(data)
                  jdObsN = cell(length(data{1,1}),1);
                  jdObs = zeros(length(data{1,1}),2);
                  for i = 1:length(data)
                      if i == 1
                         jdObsN(:,i)= data{1,i};
                      else 
                         jdObs(:,i-1) = double(data{1,i});
                      end
                   end  
                   numJ = length(jdObsN(:,1)); 
                   fclose(fid)
                   global jdObsN
                   global jdObs
                   global numJ
        end

        % Callback function
        function ImportRelFileButtonPushed(app, event)
                 global jdObsN
                 [file,path] = uigetfile({'*.*'},'Select the RelAdjustment file');
                 ImportRelFile = fullfile(path,file);
                 fid=fopen(ImportRelFile,'r');
                 data = textscan(fid,'%s %f %s %s %f %f %f %f %f %f %f %f');
                 xdObsN = cell(length(data{1,1}),4);
                 xdObsN(:,1) = data{1,1};xdObsN(:,2) = data{1,3};xdObsN(:,3) = data{1,4};
                 xdObs = zeros(length(data{1,1+4}),8);
                 for i = 1:8
                    xdObs(:,i) = double(data{1,i+4});
                 end
                 numX = length(xdObs(:,1)); 
                 unObsN = {};yiObs = {};
                 unObsN(1,1) = xdObsN(1,2);
                 yiObs(1,1) = xdObsN(1,1);
                 st1 = 1;st2 = 1;
                 for i = 1:numX
                    
                     if strcmp(yiObs,xdObsN(i,1)) == zeros(length(yiObs),1)
                         st2 = st2 + 1;
                         yiObs(st2,1) = xdObsN(i,1);
                     end
                     
                     for j = 1:2
                        if strcmp(unObsN,xdObsN(i,1+j)) == zeros(length(unObsN),1)
                           st1 = st1 + 1;
                           unObsN(st1,1) = xdObsN(i,1+j);
                        end
                     end
                 end
                 st1 = length(unObsN);
                 for i  = 1:length(jdObsN)
                     if strcmp(unObsN,jdObsN(i)) == zeros(length(unObsN),1)
                         st1 = st1 + 1;
                         unObsN(st1,1) = jdObsN(i);
                     end
                 end
                 global xdObsN
                 global xdObs
                 global numX
                 global unObsN
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
               global yiparaN
               global yipara
               global unObsN
               global jdObsN
               global jdObs
               global numJ
               global numX
               global xdObs
               global xdObsN    
               %************************************************
                                                       
               %************************************************
               if isempty(yiparaN)
                   errordlg('There are no instrunment parameter');
                   return;
               end
               if isempty(jdObsN)
                   errordlg('There are no adjustment data for absolute gravity');
                   return;
               end
               if isempty(xdObsN)
                   errordlg('There are no adjustment data for relative gravity');
                   return;
               end
               results = adjust_main(yiparaN,yipara,unObsN,jdObsN,jdObs,numJ,numX,xdObs,xdObsN,app.RobusteParameter.Value,app.K1Parameter.Value,app.K2Parameter.Value,app.Adjustment_MethodButtonGroup.SelectedObject.Text,app.InstrumentParameter.Value,app.Datum_PointCheckBox.Value,app.Basic_PointCheckBox.Value,app.PointofreferenceCheckBox.Value,app.ShortBaselineCheckBox.Value,app.residualCheckBox.Value,app.InstrumentCheckBox.Value);
        end

        % Menu selected function: ReldataMenu
        function ReldataMenuSelected(app, event)
                 global jdObsN
                 [file,path] = uigetfile({'*.*'},'Select the RelAdjustment file');
                 ImportRelFile = fullfile(path,file); %(length(data{1,1}),4);
                 fid=fopen(ImportRelFile,'r');
                 data = textscan(fid,'%s %f %s %s %f %f %f %f %f %f %f %f');
                 xdObsN = cell(length(data{1,1}),4);
                 xdObsN(:,1) = data{1,1};xdObsN(:,2) = data{1,3};xdObsN(:,3) = data{1,4};
                 xdObs = zeros(length(data{1,1+4}),8);
                 for i = 1:8
                    xdObs(:,i) = double(data{1,i+4});
                 end
                 numX = length(xdObs(:,1)); 
                 unObsN = {};yiObs = {};
                 unObsN(1,1) = xdObsN(1,2);
                 yiObs(1,1) = xdObsN(1,1);
                 st1 = 1;st2 = 1;
                 for i = 1:numX
                      
                     if strcmp(yiObs,xdObsN(i,1)) == zeros(length(yiObs),1)
                         st2 = st2 + 1;
                         yiObs(st2,1) = xdObsN(i,1);
                     end
                    
                     for j = 1:2
                        if strcmp(unObsN,xdObsN(i,1+j)) == zeros(length(unObsN),1)
                           st1 = st1 + 1;
                           unObsN(st1,1) = xdObsN(i,1+j);
                        end
                     end
                 end
                 st1 = length(unObsN);
                 for i  = 1:length(jdObsN)
                     if strcmp(unObsN,jdObsN(i)) == zeros(length(unObsN),1)
                         st1 = st1 + 1;
                         unObsN(st1,1) = jdObsN(i);
                     end
                 end
                 global xdObsN
                 global xdObs
                 global numX
                 global unObsN            
        end

        % Menu selected function: ObsdataMenu
        function ObsdataMenuSelected(app, event)
                  [file,path] = uigetfile({'*.*'},'Select the AbsAdjustment file');
                  ImportAbsFile = fullfile(path,file);
                  fid = fopen(ImportAbsFile,'r');
                  if fid<0
                      errordlg('The file for input has error');
                  end
                  data = textscan(fid,'%s %f %f');
                  length(data)
                  jdObsN = cell(length(data{1,1}),1);
                  jdObs = zeros(length(data{1,1}),2);
                  for i = 1:length(data)
                      if i == 1
                         jdObsN(:,i)= data{1,i};
                      else 
                         jdObs(:,i-1) = double(data{1,i});
                      end
                   end  
                   numJ = length(jdObsN(:,1)); 
                   fclose(fid)
                   global jdObsN
                   global jdObs
                   global numJ
        end

        % Menu selected function: Plot_graphMenu
        function Plot_graphMenuSelected(app, event)
            if  strcmp(app.CoordinateFileButton.Enable, 'off') ||  app.CoordinateFile.Value 
                errordlg('There are no coordiante data so that the graph for data disturbation  can not be plot');
            else
                results = plot_dist(app.CoordinateFile.Value);
            end
        end

        % Menu selected function: return_out
        function return_outMenuSelected(app, event)
                 app.InputFilePanel.Visible = 'off';
                 app.quasi_geodetic_2.Visible = 'on';
                 app.SetPanel.Visible = 'off';
                 app.OutlieranalysisPanel.Visible = 'off';
                 app.Observation_analysisPanel.Visible = 'off';
                 app.Parameter_modulPanel.Visible = 'off';
                 app.Adjustment_ComputePanel.Visible = 'off';   
        end

        % Menu selected function: exit_out
        function exit_outMenuSelected(app, event)
                delete(app.GravNetAdjPro)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GravNetAdjPro = uifigure('Visible', 'off');
            colormap(app.GravNetAdjPro, 'parula');
            app.GravNetAdjPro.Name = 'Data_Processing for Gravity_Datum  ';
            app.Relative_GravityMenu = uimenu(app.GravNetAdjPro);
            app.Relative_GravityMenu.MenuSelectedFcn = createCallbackFcn(app, @Relative_GravityMenuSelected, true);
            app.Relative_GravityMenu.Text = 'Relative_Gravity';
            app.Para_SetMenu = uimenu(app.Relative_GravityMenu);
            app.Para_SetMenu.MenuSelectedFcn = createCallbackFcn(app, @Para_SetMenuSelected, true);
            app.Para_SetMenu.Text = 'Para_Set';
            app.Input_DataMenu = uimenu(app.Relative_GravityMenu);
            app.Input_DataMenu.MenuSelectedFcn = createCallbackFcn(app, @Input_DataMenuSelected, true);
            app.Input_DataMenu.Text = 'Input_Data';
            app.RelGrav_CompMenu = uimenu(app.Relative_GravityMenu);
            app.RelGrav_CompMenu.MenuSelectedFcn = createCallbackFcn(app, @RelGrav_CompMenuSelected, true);
            app.RelGrav_CompMenu.Text = 'RelGrav_Comp';
            app.Outlier_detectionMenu = uimenu(app.Relative_GravityMenu);
            app.Outlier_detectionMenu.MenuSelectedFcn = createCallbackFcn(app, @Outlier_detectionMenuSelected, true);
            app.Outlier_detectionMenu.Text = 'Outlier_detection';
            app.AdjustmentData_loadMenu = uimenu(app.GravNetAdjPro);
            app.AdjustmentData_loadMenu.Text = 'AdjustmemtData_Load';
            app.ObsdataMenu = uimenu(app.AdjustmentData_loadMenu);
            app.ObsdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ObsdataMenuSelected, true);
            app.ObsdataMenu.Text = 'Obsdata';
            app.ReldataMenu = uimenu(app.AdjustmentData_loadMenu);
            app.ReldataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReldataMenuSelected, true);
            app.ReldataMenu.Text = 'Reldata';
            app.Observation_AnalysisMenu = uimenu(app.GravNetAdjPro);
            app.Observation_AnalysisMenu.Text = 'Observation_Analysis';
            app.AnalysisMenu = uimenu(app.Observation_AnalysisMenu);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisMenuSelected, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.Plot_graphMenu = uimenu(app.Observation_AnalysisMenu);
            app.Plot_graphMenu.MenuSelectedFcn = createCallbackFcn(app, @Plot_graphMenuSelected, true);
            app.Plot_graphMenu.Text = 'Plot_graph';
            app.AdjustmentMenu = uimenu(app.GravNetAdjPro);
            app.AdjustmentMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustmentMenuSelected, true);
            app.AdjustmentMenu.Text = 'GravityNetwork_adjustment';
            app.ParameterMenu = uimenu(app.AdjustmentMenu);
            app.ParameterMenu.MenuSelectedFcn = createCallbackFcn(app, @ParameterMenuSelected, true);
            app.ParameterMenu.Text = 'Parameter_set';
            app.ComputeMenu = uimenu(app.AdjustmentMenu);
            app.ComputeMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputeMenuSelected, true);
            app.ComputeMenu.Text = 'Adjustment_Compute';
            app.return_out = uimenu(app.GravNetAdjPro);
            app.return_out.MenuSelectedFcn = createCallbackFcn(app, @return_outMenuSelected, true);
            app.return_out.Text = 'Return';
            app.exit_out = uimenu(app.GravNetAdjPro);
            app.exit_out.MenuSelectedFcn = createCallbackFcn(app, @exit_outMenuSelected, true);
            app.exit_out.Text = 'Exit';
            app.InputFilePanel = uipanel(app.GravNetAdjPro);
            app.InputFilePanel.Title = 'Input File';
            app.InputFilePanel.Visible = 'off';
            app.RelativeObservationButton = uibutton(app.InputFilePanel, 'push');
            app.RelativeObservationButton.ButtonPushedFcn = createCallbackFcn(app, @RelativeObservationButtonPushed, true);
            app.RelativeObservationButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.RelativeObservationButton.Text = '';
            app.RelativeObservationFileLabel = uilabel(app.InputFilePanel);
            app.RelativeObservationFileLabel.Text = 'Relative Observation File';
            app.RelativeObservation = uieditfield(app.InputFilePanel, 'text');
            app.RelativeObservation.Editable = 'off';
            app.RelativeAdjustmentButton = uibutton(app.InputFilePanel, 'push');
            app.RelativeAdjustmentButton.ButtonPushedFcn = createCallbackFcn(app, @RelativeAdjustmentButtonPushed, true);
            app.RelativeAdjustmentButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.RelativeAdjustmentButton.Text = '';
            app.RelativeAdjustmentFileLabel = uilabel(app.InputFilePanel);
            app.RelativeAdjustmentFileLabel.Text = 'Relative adjustment File';
            app.RelativeAdjustment = uieditfield(app.InputFilePanel, 'text');
            app.RelativeAdjustment.Editable = 'off';
            app.GezhiButton = uibutton(app.InputFilePanel, 'push');
            app.GezhiButton.ButtonPushedFcn = createCallbackFcn(app, @GezhiButtonPushed, true);
            app.GezhiButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.GezhiButton.Text = '';
            app.GezhiFileLabel = uilabel(app.InputFilePanel);
            app.GezhiFileLabel.Text = 'Gezhi File';
            app.Gezhi = uieditfield(app.InputFilePanel, 'text');
            app.Gezhi.Editable = 'off';
            app.CoordinateFileButton = uibutton(app.InputFilePanel, 'push');
            app.CoordinateFileButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinateFileButtonPushed, true);
            app.CoordinateFileButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.CoordinateFileButton.Enable = 'off';
            app.CoordinateFileButton.Text = '';
            app.CoordinateFileLabel = uilabel(app.InputFilePanel);
            app.CoordinateFileLabel.Text = 'Coordiante File';
            app.CoordinateFile = uieditfield(app.InputFilePanel, 'text');
            app.CoordinateFile.Editable = 'off';
            app.CoordinateFile.Enable = 'off';
            app.GradientFileButton = uibutton(app.InputFilePanel, 'push');
            app.GradientFileButton.ButtonPushedFcn = createCallbackFcn(app, @GradientFileButtonPushed, true);
            app.GradientFileButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.GradientFileButton.Enable = 'off';
            app.GradientFileButton.Text = '';
            app.GradientFileLabel = uilabel(app.InputFilePanel);
            app.GradientFileLabel.Text = 'Gradient File';
            app.GradientFile = uieditfield(app.InputFilePanel, 'text');
            app.GradientFile.Editable = 'off';
            app.GradientFile.Enable = 'off';
            app.EditField = uieditfield(app.GravNetAdjPro, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Value = 'Copyright@2022';
            app.quasi_geodetic_2 = uibutton(app.GravNetAdjPro, 'push');
            app.quasi_geodetic_2.Icon = fullfile(pathToMLAPP, '_English.jpg');
            app.quasi_geodetic_2.Text = '';
            app.MainLabel = uilabel(app.GravNetAdjPro);
            app.MainLabel.Text = 'Gravity Network Adjustment Program';
            app.OutlieranalysisPanel = uipanel(app.GravNetAdjPro);
            app.OutlieranalysisPanel.Title = 'Oulier analysis';
            app.OutlieranalysisPanel.Visible = 'off';
            app.SetPanel_2 = uipanel(app.OutlieranalysisPanel);
            app.SetPanel_2.Title = 'Set';
            app.Limite_ValueEditFieldLabel = uilabel(app.SetPanel_2);
            app.Limite_ValueEditFieldLabel.Text = 'Limite_Value';
            app.Limite_ValueEditField = uieditfield(app.SetPanel_2, 'numeric');
            app.Limite_ValueEditField.Value = 20;
            app.InputFilePanel_2 = uipanel(app.OutlieranalysisPanel);
            app.InputFilePanel_2.Title = 'Input File';
            app.YiqigezhiButton = uibutton(app.InputFilePanel_2, 'push');
            app.YiqigezhiButton.ButtonPushedFcn = createCallbackFcn(app, @YiqigezhiButtonPushed, true);
            app.YiqigezhiButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.YiqigezhiButton.Text = '';
            app.YiqigezhiTableLabel = uilabel(app.InputFilePanel_2);
            app.YiqigezhiTableLabel.Text = 'Yiqigezhi Table';
            app.Yiqigezhi = uieditfield(app.InputFilePanel_2, 'text');
            app.Yiqigezhi.Editable = 'off';
            app.ObsFileButton = uibutton(app.InputFilePanel_2, 'push');
            app.ObsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ObsFileButtonPushed, true);
            app.ObsFileButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.ObsFileButton.Text = '';
            app.ObsFile = uieditfield(app.InputFilePanel_2, 'text');
            app.ObsFile.Editable = 'off';
            app.ObservationFileLabel = uilabel(app.InputFilePanel_2);
            app.ObservationFileLabel.Text = 'Observation File';
            app.Outlier_DetButton = uibutton(app.OutlieranalysisPanel, 'push');
            app.Outlier_DetButton.ButtonPushedFcn = createCallbackFcn(app, @Outlier_DetButtonPushed, true);
            app.Outlier_DetButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'play_button-512.png');
            app.Outlier_DetButton.Text = '粗差探测';
            app.Observation_analysisPanel = uipanel(app.GravNetAdjPro);
            app.Observation_analysisPanel.Title = 'Observation_analysis';
            app.Observation_analysisPanel.Visible = 'off';
            app.AnalysisModulePanel = uipanel(app.Observation_analysisPanel);
            app.AnalysisModulePanel.Title = 'Analysis Module';
            app.DatastatsticsPanel = uipanel(app.AnalysisModulePanel);
            app.DatastatsticsPanel.Title = 'Data statistics';
            app.ObservationsLabel = uilabel(app.DatastatsticsPanel);
            app.ObservationsLabel.Text = 'Observations';
            app.AprioriPosDD = uidropdown(app.DatastatsticsPanel);
            app.AprioriPosDD.Items = {'AbsObs', 'RelObs'};
            app.AprioriPosDD.Value = 'AbsObs';
            app.Anysis_CompButton = uibutton(app.AnalysisModulePanel, 'push');
            app.Anysis_CompButton.ButtonPushedFcn = createCallbackFcn(app, @Anysis_CompButtonPushed, true);
            app.Anysis_CompButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'play_button-512.png');
            app.Anysis_CompButton.Text = 'Anysis_Comp';
            app.DataAnalysisPanel = uipanel(app.AnalysisModulePanel);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.AnalysisLabel = uilabel(app.DataAnalysisPanel);
            app.AnalysisLabel.Text = 'Analysis';
            app.AprioriPosDD_2 = uidropdown(app.DataAnalysisPanel);
            app.AprioriPosDD_2.Items = {'ObsNetwork', 'YiqiPara', 'ConnectedGraph', 'CloseLoop'};
            app.AprioriPosDD_2.Value = 'ObsNetwork';
            app.Statistics_CompButton = uibutton(app.AnalysisModulePanel, 'push');
            app.Statistics_CompButton.ButtonPushedFcn = createCallbackFcn(app, @Statistics_CompButtonPushed, true);
            app.Statistics_CompButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'play_button-512.png');
            app.Statistics_CompButton.Text = 'Statistics_Comp';
            app.SetPanel_3 = uipanel(app.Observation_analysisPanel);
            app.SetPanel_3.Title = 'Set';
            app.CloseLoopPanel = uipanel(app.SetPanel_3);
            app.CloseLoopPanel.Title = 'CLoseLoop';
            app.ToleranceLabel = uilabel(app.CloseLoopPanel);
            app.ToleranceLabel.Text = 'Tolerance';
            app.ToleranceParameter = uieditfield(app.CloseLoopPanel, 'numeric');
            app.ToleranceParameter.RoundFractionalValues = 'on';
            app.ToleranceParameter.ValueDisplayFormat = '%f';
            app.ToleranceParameter.Editable = 'off';
            app.ToleranceParameter.Value = 20;
            app.CloseLoop_gezhiButtonGroup = uibuttongroup(app.SetPanel_3);
            app.CloseLoop_gezhiButtonGroup.Title = 'CloseLoop_gezhi';
            app.UnusedButton = uiradiobutton(app.CloseLoop_gezhiButtonGroup);
            app.UnusedButton.Text = 'Unused';
            app.UnusedButton.Value = true;
            app.usedButton = uiradiobutton(app.CloseLoop_gezhiButtonGroup);
            app.usedButton.Text = ' Used';
            app.Parameter_modulPanel = uipanel(app.GravNetAdjPro);
            app.Parameter_modulPanel.Title = 'Parameter_modul';
            app.InputPanel = uipanel(app.Parameter_modulPanel);
            app.InputPanel.Title = 'Input';
            app.InstrumentParameterLabel = uilabel(app.InputPanel);
            app.InstrumentParameterLabel.Text = 'Instrument Parameter';
            app.InstrumentParameter = uieditfield(app.InputPanel, 'text');
            app.InstrumentParameter.Editable = 'off';
            app.InstrumentParameterButton = uibutton(app.InputPanel, 'push');
            app.InstrumentParameterButton.ButtonPushedFcn = createCallbackFcn(app, @InstrumentParameterButtonPushed, true);
            app.InstrumentParameterButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'icon-folder-128.png');
            app.InstrumentParameterButton.Text = '';
            app.SetPanel_5 = uipanel(app.Parameter_modulPanel);
            app.SetPanel_5.Title = 'Set';
            app.Robust_SetPanel = uipanel(app.SetPanel_5);
            app.Robust_SetPanel.Title = 'Robust Set';
            app.k1Label = uilabel(app.Robust_SetPanel);
            app.k1Label.Text = 'k1';
            app.K1Parameter = uieditfield(app.Robust_SetPanel, 'numeric');
            app.K1Parameter.ValueDisplayFormat = '%.1f';
            app.K1Parameter.Value = 1.5;
            app.k2Label = uilabel(app.Robust_SetPanel);
            app.k2Label.Text = 'k2';
            app.K2Parameter = uieditfield(app.Robust_SetPanel, 'numeric');
            app.K2Parameter.ValueDisplayFormat = '%.1f';
            app.K2Parameter.Value = 4.5;
            app.e1Label = uilabel(app.Robust_SetPanel);
            app.e1Label.Text = 'e1';
            app.RobusteParameter = uieditfield(app.Robust_SetPanel, 'numeric');
            app.RobusteParameter.ValueDisplayFormat = '%.1f';
            app.RobusteParameter.Value = 0.2;
            app.iter_number = uieditfield(app.Robust_SetPanel, 'text');
            app.iter_number.Editable = 'off';
            app.iter_numberLabel = uilabel(app.Robust_SetPanel);
            app.iter_numberLabel.Text = 'iter_number';
            app.VCESetPanel = uipanel(app.SetPanel_5);
            app.VCESetPanel.Title = 'VCE Set';
            app.Num_ObsLabel = uilabel(app.VCESetPanel);
            app.Num_ObsLabel.Enable = 'off';
            app.Num_ObsLabel.Text = 'Num_Obs';
            app.Num_ObsParameter = uieditfield(app.VCESetPanel, 'numeric');
            app.Num_ObsParameter.ValueDisplayFormat = '%.1f';
            app.Num_ObsParameter.Enable = 'off';
            app.Num_ObsParameter.Value = 2;
            app.e2Label = uilabel(app.VCESetPanel);
            app.e2Label.Enable = 'off';
            app.e2Label.Text = 'e2';
            app.VCEeParameter = uieditfield(app.VCESetPanel, 'numeric');
            app.VCEeParameter.ValueDisplayFormat = '%.1f';
            app.VCEeParameter.Enable = 'off';
            app.VCEeParameter.Value = 0.1;
            app.Adjustment_MethodButtonGroup = uibuttongroup(app.SetPanel_5);
            app.Adjustment_MethodButtonGroup.Title = 'Adjustment_Method';
            app.LSButton = uiradiobutton(app.Adjustment_MethodButtonGroup);
            app.LSButton.Text = 'LS';
            app.RobustButton = uiradiobutton(app.Adjustment_MethodButtonGroup);
            app.RobustButton.Text = 'Robust';
            app.RobustButton.Value = true;
            app.Adjustment_ComputePanel = uipanel(app.GravNetAdjPro);
            app.Adjustment_ComputePanel.Title = 'Adjustment_Compute';
            app.Adjustment_ComputePanel.Visible = 'off';
            app.StatisticsPanel = uipanel(app.Adjustment_ComputePanel);
            app.StatisticsPanel.Title = 'Statistics';
            app.residualCheckBox = uicheckbox(app.StatisticsPanel);
            app.residualCheckBox.Text = 'resiudal';
            app.Datum_PointCheckBox = uicheckbox(app.StatisticsPanel);
            app.Datum_PointCheckBox.Text = 'Datum_Point';
            app.Basic_PointCheckBox = uicheckbox(app.StatisticsPanel);
            app.Basic_PointCheckBox.Text = 'Basic_Point';
            app.East_westCheckBox = uicheckbox(app.StatisticsPanel);
            app.East_westCheckBox.Text = 'East_west';
            app.PointofreferenceCheckBox = uicheckbox(app.StatisticsPanel);
            app.PointofreferenceCheckBox.Text = 'Point of reference';
            app.InstrumentCheckBox = uicheckbox(app.StatisticsPanel);
            app.InstrumentCheckBox.Text = 'Instrument';
            app.ShortBaselineCheckBox = uicheckbox(app.StatisticsPanel);
            app.ShortBaselineCheckBox.Text = 'Short Baseline';
            app.ComputePanel = uipanel(app.Adjustment_ComputePanel);
            app.ComputePanel.Title = 'Compute';
            app.RunButton = uibutton(app.ComputePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, '新建文件夹', 'play_button-512.png');
            app.RunButton.Text = 'Adj_Run';
            app.SetPanel = uipanel(app.GravNetAdjPro);
            app.SetPanel.Title = 'Set';
            app.SetPanel.Visible = 'off';
            app.ErrorCorrectionPanel = uipanel(app.SetPanel);
            app.ErrorCorrectionPanel.Title = 'Error Correction';
            app.SolidTideCheckBox = uicheckbox(app.ErrorCorrectionPanel);
            app.SolidTideCheckBox.Text = 'Solid Tide';
            app.OceanTideCheckBox = uicheckbox(app.ErrorCorrectionPanel);
            app.OceanTideCheckBox.Text = 'Ocean Tide';
            app.AtmosphericPressureCheckBox = uicheckbox(app.ErrorCorrectionPanel);
            app.AtmosphericPressureCheckBox.Text = 'Atmospheric Pressure';
            app.Parameter_SelectionPanel = uipanel(app.SetPanel);
            app.Parameter_SelectionPanel.Title = 'Parameter_Selection';
            app.GradientSelectionButtonGroup = uibuttongroup(app.Parameter_SelectionPanel);
            app.GradientSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GradientSelectionButtonGroupSelectionChanged, true);
            app.GradientSelectionButtonGroup.Title = 'Gradient Selection';
            app.NewGradButton = uiradiobutton(app.GradientSelectionButtonGroup);
            app.NewGradButton.Text = 'NewGrad';
            app.OldGradButton = uiradiobutton(app.GradientSelectionButtonGroup);
            app.OldGradButton.Text = 'OldGrad';
            app.OldGradButton.Value = true;
            app.CodeSelectionButtonGroup = uibuttongroup(app.Parameter_SelectionPanel);
            app.CodeSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CodeSelectionButtonGroupSelectionChanged, true);
            app.CodeSelectionButtonGroup.Title = 'Code Selection';
            app.UTF8Button = uiradiobutton(app.CodeSelectionButtonGroup);
            app.UTF8Button.Text = 'UTF-8';
            app.GBKButton = uiradiobutton(app.CodeSelectionButtonGroup);
            app.GBKButton.Text = 'GBK';
            app.GBKButton.Value = true;
            app.CoordinateSelectionButtonGroup = uibuttongroup(app.Parameter_SelectionPanel);
            app.CoordinateSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoordinateSelectionButtonGroupSelectionChanged, true);
            app.CoordinateSelectionButtonGroup.Title = 'Coordiante Selection';
            app.NewCoordButton = uiradiobutton(app.CoordinateSelectionButtonGroup);
            app.NewCoordButton.Text = 'NewCood';
            app.OldCoordButton = uiradiobutton(app.CoordinateSelectionButtonGroup);
            app.OldCoordButton.Text = 'OldCoord';
            app.OldCoordButton.Value = true;
            app.InstrumentHightCheckBox = uicheckbox(app.SetPanel);
            app.InstrumentHightCheckBox.Text = 'Instrument Hight';
            % Show the figure after all components are created
            app.GravNetAdjPro.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GravNetAdj_English

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GravNetAdjPro)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GravNetAdjPro)
        end
    end
end